classdef hreflex_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NIDeviceDropDownLabel         matlab.ui.control.Label
        nidev                         matlab.ui.control.DropDown
        NumberofpulsesEditFieldLabel  matlab.ui.control.Label
        npulses                       matlab.ui.control.NumericEditField
        PulsetypeDropDownLabel        matlab.ui.control.Label
        pulsetype                     matlab.ui.control.DropDown
        pulsewidth                    matlab.ui.control.NumericEditField
        DelaytimesEditFieldLabel      matlab.ui.control.Label
        delay                         matlab.ui.control.NumericEditField
        PulsewidthsEditFieldLabel     matlab.ui.control.Label
        SamplerateEditFieldLabel      matlab.ui.control.Label
        AmplitudemAEditFieldLabel     matlab.ui.control.Label
        pulseamp                      matlab.ui.control.NumericEditField
        Fs                            matlab.ui.control.NumericEditField
        singlepulse                   matlab.ui.control.UIAxes
        pulsetrain                    matlab.ui.control.UIAxes
        finalplot                     matlab.ui.control.UIAxes
        startsession                  matlab.ui.control.Button
        MinimumcurrentamplitudemAEditFieldLabel  matlab.ui.control.Label
        minrec                        matlab.ui.control.NumericEditField
        MaximumcurrentamplitudemAEditFieldLabel  matlab.ui.control.Label
        maxrec                        matlab.ui.control.NumericEditField
        StepsizemAEditFieldLabel      matlab.ui.control.Label
        steprec                       matlab.ui.control.NumericEditField
        RecruitmentCurveLabel         matlab.ui.control.Label
        SaveOptionsLabel              matlab.ui.control.Label
        FilenameEditFieldLabel        matlab.ui.control.Label
        filename                      matlab.ui.control.EditField
        TreatmentLabel                matlab.ui.control.Label
        CNOstate                      matlab.ui.control.DropDown
        autosave                      matlab.ui.control.CheckBox
        startrec                      matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        GeneratePlot                  matlab.ui.control.Button
        InputDropDownLabel            matlab.ui.control.Label
        InputDropDown                 matlab.ui.control.DropDown
        OutputDropDownLabel           matlab.ui.control.Label
        OutputDropDown                matlab.ui.control.DropDown
        TrialLabel                    matlab.ui.control.Label
        LabTri                        matlab.ui.control.Label
        DirectoryLabel                matlab.ui.control.Label
        DirLabel                      matlab.ui.control.Label
        SelectdefaultCheckBox         matlab.ui.control.CheckBox
        PeriodsecondsEditFieldLabel   matlab.ui.control.Label
        Period                        matlab.ui.control.NumericEditField
        minivar                       matlab.ui.control.CheckBox
        Triplicates                   matlab.ui.control.CheckBox
        NotesEditFieldLabel           matlab.ui.control.Label
        Notes                         matlab.ui.control.EditField
        CurrentDropDownLabel          matlab.ui.control.Label
        Current                       matlab.ui.control.DropDown
        VoltageDropDownLabel          matlab.ui.control.Label
        Voltage                       matlab.ui.control.DropDown
        TestingInputsLabel            matlab.ui.control.Label
        MeasuredImpedanceLabel        matlab.ui.control.Label
        impedance                     matlab.ui.control.Label
        TestImpedanceButton           matlab.ui.control.Button
        ParametersLabel               matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        InterpulsesEditFieldLabel     matlab.ui.control.Label
        Interpulse                    matlab.ui.control.NumericEditField
        QuickTrigButton               matlab.ui.control.Button
        QuickTrig                     matlab.ui.control.Knob
        Analyze                       matlab.ui.control.Button
        MHcutoffmsEditFieldLabel      matlab.ui.control.Label
        MHcutoff                      matlab.ui.control.NumericEditField
        LoadDataButton                matlab.ui.control.Button
        LoadLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        trial; %increments for autosaving purposes
        s; %for the daq session
        PulseTrain; %computation for pulse train wave
        SinglePulse; %computation for single pulse wave
        data2; %recorded data
        traintime;
        Data;
        indices;
        Time;
        Pulse_Train;
        MFP;HFP;MoH;M;H;
        loadedfile;
        adaptation;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startsession
        function startsessionButtonPushed(app, event)
            app.trial=1;
            a=app.trial;
            app.s=daq.createSession('ni');
            app.s.DurationInSeconds = length(app.PulseTrain)/app.Fs.Value;
            app.s.Rate=app.Fs.Value;
            
            if app.SelectdefaultCheckBox.Value == 1
                % populate the DAQ popup menu. see if we have DAQ toolbox.
                % if so, look for DAQ board. if have one, select it.
                tb=ver;
                tbn={tb.Name};
                %Setting up dev ID
                if ismember('Data Acquisition Toolbox',tbn)
                    gd = daq.getDevices();
                    
                    if isempty(gd) == 1
                        app.nidev.Value ='Sim ';
                    else
                        devID = gd(1).ID;
                        app.nidev.Value = num2str(devID);
                    end
                    
                    % check for DAQ boards, look for NI, choose first one, populate the pop
                    % up menu. set handles.daq accordingly
                else
                    % Don't have DAQ, make Sim  the only option.
                    app.nidev.Value ='Sim ';
                    
                end
                %Setting analog inputs and outputs
                if app.nidev.Value == 'Sim ' %if Sim , dont assign analog inputs/outputs, just update trial number
                    app.LabTri.Text = num2str(a);
                else
                    if app.Current.Value ~= 'N/A' & app.Voltage.Value ~= 'N/A'
                
                        if app.Current.Value == app.Voltage.Value | app.Current.Value == app.InputDropDown.Value | app.Voltage.Value == app.InputDropDown.Value
                
                            app.impedance.Text = 'Input Error'
                            app.impedance.FontColor = [1 0 0];
                            app.impedance.FontWeight = 'bold';
                        end
                    end
                    addAnalogInputChannel(app.s,app.nidev.Value,app.InputDropDown.Value,'Voltage') %recording EMG
                    addAnalogOutputChannel(app.s,app.nidev.Value,app.OutputDropDown.Value,'Voltage') %delivering electric stim
                    
                    if app.Current.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Current.Value,'Voltage') %current testing 
                    end

                    if app.Voltage.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Voltage.Value,'Voltage') %voltage testing
                    end
                    
                    
                    app.LabTri.Text = num2str(a);
                end
                
            else
                if app.nidev.Value == 'Sim ' %if Sim , dont assign analog inputs/outputs, just update trial number
                    app.LabTri.Text = num2str(a);
                else
                   addAnalogInputChannel(app.s,app.nidev.Value,app.InputDropDown.Value,'Voltage') %recording EMG
                   addAnalogOutputChannel(app.s,app.nidev.Value,app.OutputDropDown.Value,'Voltage') %delivering electric stim
                    
                    if app.Current.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Current.Value,'Voltage') %current testing 
                    end

                    if app.Voltage.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Voltage.Value,'Voltage') %voltage testing
                    end
                    
                    app.LabTri.Text = num2str(a);
                end
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Setting the trial number label
            a=app.trial;
            app.LabTri.Text = num2str(a);
            app.loadedfile = [];
            %Creating the pulse train
            DelayTime = zeros(app.Fs.Value*app.delay.Value,1);
            
            if app.pulsetype.Value == 'Bipolar EstimPulse'
                Pulse = [app.pulseamp.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1) ;...
                    -1*app.pulseamp.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];
            end
            if app.pulsetype.Value == 'Positive Monopolar'
                Pulse = [app.pulseamp.Value*ones(app.Fs.Value * app.pulsewidth.Value*1e-6,1)];
            end
            if  app.pulsetype.Value == 'Negative Monopolar'
                Pulse = [-1*app.pulseamp.Value*ones(app.Fs.Value * app.pulsewidth.Value*1e-6,1)];
                
            end
            %Creating a variable called SinglePulse to visualize the waveform of a single pulse
            app.SinglePulse = [zeros(app.Fs.Value*2e-6*app.pulsewidth.Value,1);Pulse;...
                zeros(app.Fs.Value*2e-6*app.pulsewidth.Value,1)];
            
            
            %Creating the desired pulse train
            Pulse2 = [Pulse;zeros((app.Fs.Value*app.Interpulse.Value),1)];
            app.PulseTrain = [DelayTime;repmat(Pulse2,app.npulses.Value,1)];
            
            %Creating time vectors
            singletime = [(1:length(app.SinglePulse))/app.Fs.Value];
            app.traintime  = [(1:length(app.PulseTrain))/app.Fs.Value];
            
            %Plotting single pulse and pulse trains
            plot(app.singlepulse,singletime,app.SinglePulse);
            xlabel(app.singlepulse,'Time (s)');
            ylabel(app.singlepulse,'Amplitude (mA)');
            title(app.singlepulse,'Single Pulse');
            plot(app.pulsetrain,app.traintime,app.PulseTrain);
            xlabel(app.pulsetrain,'Time (s)');
            ylabel(app.pulsetrain,'Amplitude (mA)');
            
            %Acquiring Data
            if app.nidev.Value =='Sim '
                L = length(app.PulseTrain);
                app.data2 = -0.1*rand(L,1)+0.05;
                
            else
                queueOutputData(app.s,app.PulseTrain);
                
                data=startForeground(app.s);
                
                %Filtering acquired data
                filty = designfilt('bandstopiir','FilterOrder',2, ...
                    'HalfPowerFrequency1',59,'HalfPowerFrequency2',61, ...
                    'DesignMethod','butter','SampleRate',app.Fs.Value);
                %this is a notch filter to remove 60Hz frequency from acquired data
                app.data2=filtfilt(filty,data(:,1));
            end
            
            %plotting the response
            plot(app.finalplot,app.traintime,app.PulseTrain,'r',app.traintime,app.data2,'k');
            xlabel(app.finalplot, 'Time (s)');
            ylabel(app.finalplot, 'Amplitude');
            legend(app.finalplot, 'Electric Stimulation','Response');
            
            %Autosaving
            if app.autosave.Value == 1
                FileName=[app.filename.Value,'_',app.CNOstate.Value,'_Trial_',num2str(app.trial)];
                if app.minivar.Value == 1
                    threshold = app.PulseTrain >0;
                    app.indices = find ((diff(threshold))>0.5);
                    
                    d2 = app.data2(:,1);
                    
                    for i= 1:(length(app.indices))
                        app.Pulse_Train {i} = app.PulseTrain( ...
                            (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                            (app.indices(i) + (18e-3 * app.Fs.Value)) );
                        app.Data {i} = d2( (app.indices(i)- ...
                            (2e-3 * app.Fs.Value)) : (app.indices(i) ...
                            + (18e-3 * app.Fs.Value)) );
                        app.Time {i} = linspace (-2e-3,18e-3,length(app.Pulse_Train{i}));
                        
                        
                    end
                    Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;indices=app.indices;
                    save (FileName,'app','Time','Data','Pulse_Train','indices')
                    %Saving Variables on Excel
%                     T1=cell2mat(app.Time');
%                     T1=T1';
%                     P1=cell2mat(app.Pulse_Train);
%                     Data1=cell2mat(app.Data);
%                     
%                     X=cell(size(P1,1),2*size(P1,2)+1);
%                     
%                     X(:,1)=num2cell(T1(:,1));
%                     
%                     for i=2:2:2*size(P1,2)
%                         
%                         X(:,i)=num2cell(Data1(:,i/2));
%                         X(:,i+1)=num2cell(P1(:,i/2));
%                     end
%                     
%                     file={'Time'};
%                     for i=2:2:2*size(P1,2)
%                         n=i/2;
%                         f=['Pulse',num2str(n)];
%                         fi=['Data',num2str(n)];
%                         file=[file;f;fi];
%                     end
%                     file=file';
%                     X=[file;X];
%                     
                    %xlswrite(FileName,X);
                    
                else
                    app.Time = app.traintime;
                    app.Data = app.data2;
                    app.Pulse_Train = app.PulseTrain;
                    
                    Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;
                    save (FileName,'app','Time','Data','Pulse_Train')
                    b=cd;
                    CDname=[b,'/',FileName];
                    app.DirLabel.Text = num2str(CDname);
                end
                
            end
            b=cd;
            
            if app.autosave.Value == 1
            CDname=[b,'/',FileName,'.mat'];
            app.DirLabel.Text = num2str(CDname);
            
            end
            
            
            app.trial = app.trial +1;
            app.adaptation = 1;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            
            FileName=[app.filename.Value,'_',app.CNOstate.Value,'_Trial_',num2str(app.trial)];
            Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;indices=app.indices;
            save (FileName,'app','Time','Data','Pulse_Train','indices')
            b=cd;
            CDname=[b,'/',FileName,'.mat'];
            app.DirLabel.Text = num2str(CDname);
        end

        % Button pushed function: GeneratePlot
        function GeneratePlotButtonPushed(app, event)
            figure (1)
            
            plot(app.traintime,app.PulseTrain,'r',...
                app.traintime,app.data2,'k') %plotting pluse train and acquired data against time
            legend('Electric Stimulation','Response')
            xlabel('Time');
            ylabel('Amplitude');
        end

        % Button pushed function: startrec
        function startrecButtonPushed(app, event)
            %Recruitment Curve based on minimum value, maximum value and step size
            app.adaptation=0;
            a=app.trial;
            app.loadedfile = [];
            app.LabTri.Text = num2str(a);
            
            int = app.minrec.Value; %setting amplitude as the minimum recruitment value
            onepulse = 0;
            
            SP = [int*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1) ;...
                    -int*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];

                while int <= app.maxrec.Value
                %Create pulse as long as the amplitude is lesser than the maximum recruitment value
                A = [zeros(200*1e-3*app.Fs.Value,1);...
                    int*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1) ;...
                    -int*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1);...
                    zeros(int32((app.Period.Value)-(100*1e-6)-(200*1e-3))*(app.Fs.Value),1)];
                
                if app.Triplicates.Value == 1
                    A = repmat(A,3,1);
                end
                
                onepulse = [onepulse;A];
                
                %every pulse lasts for one second
                
                int = int + app.steprec.Value; %incrementing by the step size
            end
            app.PulseTrain = [zeros(800*1e-3*app.Fs.Value,1); onepulse;zeros(50e-3*app.Fs.Value,1)];
            app.traintime = [(1:length(app.PulseTrain))/app.Fs.Value];
            
            %Plotting the Pulse Train
            %Creating a variable called SinglePulse to visualize the waveform of a single pulse
            app.SinglePulse = [zeros(app.Fs.Value*2e-6*app.pulsewidth.Value,1);SP;...
                zeros(app.Fs.Value*2e-6*app.pulsewidth.Value,1)];
       
            
            %Creating time vectors
            singletime = [(1:length(app.SinglePulse))/app.Fs.Value];

            
            %Plotting single pulse and pulse trains
            plot(app.singlepulse,singletime,app.SinglePulse);
            xlabel(app.singlepulse,'Time (s)');
            ylabel(app.singlepulse,'Amplitude (mA)');
            title(app.singlepulse,'Single Pulse');
            
            
            plot(app.pulsetrain,app.traintime,app.PulseTrain);
            xlabel(app.pulsetrain,'Time (s)');
            ylabel(app.pulsetrain,'Amplitude (mA)');
            
            %Acquiring Data
            if app.nidev.Value =='Sim '
                L = length(app.PulseTrain);
                app.data2 = -0.1*rand(L,1)+0.05;
                
            else
                queueOutputData(app.s,app.PulseTrain);
                
                data=startForeground(app.s);
                
                %Filtering acquired data
                filty = designfilt('bandstopiir','FilterOrder',2, ...
                    'HalfPowerFrequency1',59,'HalfPowerFrequency2',61, ...
                    'DesignMethod','butter','SampleRate',app.Fs.Value);
                %this is a notch filter to remove 60Hz frequency from acquired data
                app.data2=filtfilt(filty,data(:,1));
            end
            
            %plotting the response
            plot(app.finalplot,app.traintime,app.PulseTrain,'r',app.traintime,app.data2(:,1),'k');
            xlabel(app.finalplot, 'Time (s)');
            ylabel(app.finalplot, 'Amplitude');
            legend(app.finalplot, 'Electric Stimulation','Response');
            
            %Saving data
            FileName=[app.filename.Value,'_Recruitment_Curve_',app.CNOstate.Value,'_',num2str(app.trial)];
            
            if app.minivar.Value == 1
                threshold = app.PulseTrain >0;
                app.indices = find ((diff(threshold))>0.5);
                
                
                for i= 1:(length(app.indices))
                    app.Pulse_Train {i} = app.PulseTrain( ...
                        (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                        (app.indices(i) + (18e-3 * app.Fs.Value)) );
                    
                        d2=app.data2(:,1);
                    app.Data {i} = d2( (app.indices(i)- ...
                        (2e-3 * app.Fs.Value)) : (app.indices(i) ...
                        + (18e-3 * app.Fs.Value)) );
                    app.Time {i} = linspace (-2e-3,18e-3,length(app.Pulse_Train{i}));
                    
                    
                end
                Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;indices=app.indices;
                save (FileName,'app','Time','Data','Pulse_Train','indices')
%                 %Saving variables on Excel
%                 T1=cell2mat(app.Time');
%                 T1=T1';
%                 P1=cell2mat(app.Pulse_Train);
%                 Data1=cell2mat(app.Data);
%                 
%                 X=cell(size(P1,1),2*size(P1,2)+1);
%                 
%                 X(:,1)=num2cell(T1(:,1));
%                 
%                 for i=2:2:2*size(P1,2)
%                     
%                     X(:,i)=num2cell(Data1(:,i/2));
%                     X(:,i+1)=num2cell(P1(:,i/2));
%                 end
%                 
%                 file={'Time'};
%                 for i=2:2:2*size(P1,2)
%                     n=i/2;
%                     f=['Pulse',num2str(n)];
%                     fi=['Data',num2str(n)];
%                     file=[file;f;fi];
%                 end
%                 file=file';
%                 X=[file;X];
%                 
%                 %xlswrite(FileName,X);
            else
                app.Time = app.traintime;
                app.Data = app.data2(:,1);
                app.Pulse_Train = app.PulseTrain;
                
                
                Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;
                save (FileName,'app','Time','Data','Pulse_Train')
            end
            
            if app.autosave.Value == 1
            b=cd;
            CDname=[b,'/',FileName,'.mat'];
            app.DirLabel.Text = num2str(CDname);
            end


            app.trial = app.trial +1;
        end

        % Button pushed function: TestImpedanceButton
        function TestImpedanceButtonPushed(app, event)
            
            if isempty(app.trial) == 1
                app.impedance.Text = 'Start Session';
                app.impedance.FontColor = [1 0 0];
                app.impedance.FontWeight = 'bold';
            
            else
            
            if app.Current.Value == 'N/A' | app.Voltage.Value == 'N/A'
                        app.impedance.Text = 'Choose Inputs';
                        app.impedance.FontColor = [1 0 0];
                        app.impedance.FontWeight = 'bold';
            elseif app.Current.Value == app.Voltage.Value | ...
                   app.Current.Value == app.InputDropDown.Value  | ...
                   app.Voltage.Value == app.InputDropDown.Value 
                  
                        app.impedance.Text = 'Error Inputs';
                        app.impedance.FontColor = [1 0 0];
                        app.impedance.FontWeight = 'bold';
                    
            else
                
            
                testramp = linspace(0,1,app.Fs.Value)'; 
                %ramp from 0 to 1 mA for 1 second
                
           
                queueOutputData(app.s,testramp);
                data=startForeground(app.s);
                
               
                vv = 100*data((app.Fs.Value/100):app.Fs.Value,3);
                ii = data((app.Fs.Value/100):app.Fs.Value,2);
                
                
                iim = 1e-3*ii;
            
            %plotting the test results
            
            plot(app.singlepulse,ii(:,1),vv(:,1));
                        
            xlabel(app.singlepulse,'Current (mA)');
            ylabel(app.singlepulse,'Voltage (V)');
            title(app.singlepulse,'Testing Results');
            
            plot(app.pulsetrain,testramp((app.Fs.Value/100):app.Fs.Value),ii(:,1));
            xlabel(app.pulsetrain,'Command (mA)');
            ylabel(app.pulsetrain,'Response (mA)');
            title(app.pulsetrain,'Current Accuracy');
            
            polycom = polyfit(testramp((app.Fs.Value/100):app.Fs.Value),ii,1);
            
            comtxt = ['y = ',num2str(polycom(1)),' * x + ',num2str(polycom(2))];
            text(app.pulsetrain,min(ii)+0.1,max(testramp((app.Fs.Value/100):app.Fs.Value)),comtxt);
            
            
            %creating a fit and displaying the equation
            p=polyfit(iim,vv,1);
            Rleg = p(1) -1000;
            
            if Rleg < 5000 && Rleg > 15 000
                app.impedance.FontColor = [1 0 0];
                app.impedance.FontWeight = 'bold';
            else 
                app.impedance.FontColor = [0 1 0];
                app.impedance.FontWeight = 'bold';
            end
            
            impy = [num2str(Rleg) 'ohms'];
            
            app.impedance.Text = impy;
            txt = ['y = ',num2str(Rleg),' * x + ',num2str(p(2))];
            text(app.singlepulse,min(ii)+0.1,max(vv),txt);


            end
            end
   
        end

        % Button pushed function: QuickTrigButton
        function QuickTrigButtonPushed(app, event)
            
            %Creating the pulse train
            Pulse = [app.QuickTrig.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1) ;...
                -app.QuickTrig.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];


            %Creating the quick trigger pulse
            app.SinglePulse = [zeros(app.Fs.Value*100e-6,1);Pulse;...
                zeros(app.Fs.Value*100e-6,1)];
            
            app.PulseTrain = [zeros(app.Fs.Value*200e-6,1);Pulse;...
                zeros(app.Fs.Value*20e-3,1)];
            
            %Creating time vectors
            singletime = [(1:length(app.SinglePulse))/app.Fs.Value];
            app.traintime  = [(1:length(app.PulseTrain))/app.Fs.Value];
            
            %Plotting single pulse and pulse trains
            plot(app.singlepulse,singletime,app.SinglePulse);
            xlabel(app.singlepulse,'Time (s)');
            ylabel(app.singlepulse,'Amplitude (mA)');
            title(app.singlepulse,'Single Pulse');
            
            plot(app.pulsetrain,app.traintime,app.PulseTrain);
            xlabel(app.pulsetrain,'Time (s)');
            ylabel(app.pulsetrain,'Amplitude (mA)');
            
            %Acquiring Data
            if app.nidev.Value =='Sim '
                L = length(app.PulseTrain);
                app.data2 = zeros(L,1);
                
            else
                queueOutputData(app.s,app.PulseTrain);
                data=startForeground(app.s);
                
                %Filtering acquired data
                filty = designfilt('bandstopiir','FilterOrder',2, ...
                    'HalfPowerFrequency1',59,'HalfPowerFrequency2',61, ...
                    'DesignMethod','butter','SampleRate',app.Fs.Value);
                %this is a notch filter to remove 60Hz frequency from acquired data
                app.data2=filtfilt(filty,data(:,1));
            end
            
            %plotting the response
            plot(app.finalplot,app.traintime,app.PulseTrain,'r',app.traintime,app.data2(:,1),'k');
            xlabel(app.finalplot, 'Time (s)');
            ylabel(app.finalplot, 'Amplitude');
            legend(app.finalplot, 'Electric Stimulation','Response');
        end

        % Button pushed function: Analyze
        function AnalyzeButtonPushed(app, event)
        if isempty(app.loadedfile) == 0
            app.indices=app.loadedfile.indices;
            app.Data = app.loadedfile.Data;
            app.Time = app.loadedfile.Time;
            app.Pulse_Train = app.loadedfile.Pulse_Train;
        
        end

        cutoff = (app.MHcutoff.Value+2)*1e-3*app.Fs.Value; %acquisition starts 2ms before stim, then determining point value in X
        for i=1:(length(app.indices))
            dat = app.Data{i};
            
            datnoise = dat(1:185);
            noiselevel=1.75*max(abs(datnoise));
            
            
            datM = dat(250:cutoff); %M wave should be between 250 (afer stim artifact) and the H/M cutoff
            datH = dat(cutoff:length(dat));%H wave should be afer H/M cutoff
            
            maxM = max(abs(datM));
            maxH = max(abs(datH));
            
            maxMp = max(datM);
            maxHp = max(datH);
            
            if maxMp ~= maxM
            maxMp = - maxM;
            end
            if maxHp ~= maxH
            maxHp = -maxH;
            end
            
            
            locM = find(dat==maxMp);
            locH = find(dat==maxHp);
            
            if maxM < noiselevel
            maxMp = 0;
            maxM = 0;
            end
            if maxH < noiselevel
            maxHp = 0;
            maxH = 0;
            end
           
            int=max(app.Pulse_Train{i});
            figure(i) 
            plot(app.Time{i},dat);
            xline(app.MHcutoff.Value*1e-3,'r','M/H Cutoff');
            hold on
            if maxMp ~= 0 && maxHp ~= 0
                plot(locM/1e5-2e-3,maxMp,'m*')
                plot(locH/1e5-2e-3,maxHp,'m*')
            elseif maxMp == 0 && maxHp ~= 0
                plot(locH/1e5-2e-3,maxHp,'m*')
                text(4e-3,0,'No M')
            elseif maxMp ~= 0 && maxHp == 0
                plot(locM/1e5-2e-3,maxMp,'m*')
                text(12e-3,0,'no H')
            elseif maxMp == 0 && maxHp == 0
                text(4e-3,0,'No M')
                text(12e-3,0,'no H')
            end
            hold off
            label = ['Stim at ',num2str(int),'mA'];
            xlabel('time(s)')
            ylabel('Response (x1000 V)')
            title(label)
            
            M{i}= maxM;

            H{i}= maxH;
            
        end
        app.MFP = M{1};
        app.HFP = H{1};
        for i=2:(length(app.indices))
            app.MFP = [app.MFP M{i}];
            app.HFP = [app.HFP H{i}];
        end
        app.MoH = app.HFP./app.MFP; %vector containing the H to M ratios
       
        HMmax=max(app.HFP)/max(app.MFP);
        IVals = max(app.Pulse_Train{1});
        for i=2:length(app.Pulse_Train)
            IVals=[IVals max(app.Pulse_Train{i})]; %compute current intensities in IVals
        end
        if IVals(1) == IVals(2) %when loading data, if all IVals members are the same, then a frequency adapation was performed
            app.adaptation =1;
        end
        
        if app.adaptation == 1
            IVals = 1:length(IVals); %if freq adaptation function is used, instead of plotting against current values, plot against pulse number
        end
            
        figure()
        plot(IVals, app.MFP,'*-r',IVals,app.HFP,'*-b') %plot M and H versus I
        
        ylabel('Voltage (V)')
        legend('M Value','H Value')
        if app.adaptation == 1
            title('Variation of M and H Values with different pulses')
            xlabel('Pulse number')
        else
            title('Variation of M and H values with E-stim ampltitudes')
            xlabel('Current (mA)')
        end
        
        figure()
        plot(IVals,app.MoH,'-o')
        yline(HMmax,'r','Hmax/Mmax')
        xlabel('Current(mA)')
        ylabel('H to M ratio')
        if app.adaptation == 1
            title('Variation of H to M ratios with different pulses')
            xlabel('Pulse number')
        else
            title('Variation of H to M ratios with E-stim amplitudes')
            xlabel('Current (mA)')
        end
        
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            figure(app.UIFigure);

            if isequal(file,0)
             app.LoadLabel.Text='User Cancelled';
            else
             app.LoadLabel.Text=num2str(fullfile(path,file));
             app.loadedfile = load(fullfile(path,file));
             

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NIDeviceDropDownLabel = uilabel(app.UIFigure);
            app.NIDeviceDropDownLabel.Text = 'NI Device';
            app.nidev = uidropdown(app.UIFigure);
            app.nidev.Items = {'Dev1', 'Dev2', 'Dev3', 'Dev4', 'Sim '};
            app.nidev.Value = 'Dev1';
            app.NumberofpulsesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpulsesEditFieldLabel.Text = 'Number of pulses';
            app.npulses = uieditfield(app.UIFigure, 'numeric');
            app.npulses.Value = 17;
            app.PulsetypeDropDownLabel = uilabel(app.UIFigure);
            app.PulsetypeDropDownLabel.Text = 'Pulse type';
            app.pulsetype = uidropdown(app.UIFigure);
            app.pulsetype.Items = {'Bipolar EstimPulse', 'Positive Monopolar', 'Negative Monopolar'};
            app.pulsetype.Value = 'Bipolar EstimPulse';
            app.pulsewidth = uieditfield(app.UIFigure, 'numeric');
            app.pulsewidth.Value = 100;
            app.DelaytimesEditFieldLabel = uilabel(app.UIFigure);
            app.DelaytimesEditFieldLabel.Text = 'Delay time (s)';
            app.delay = uieditfield(app.UIFigure, 'numeric');
            app.delay.Value = 1;
            app.PulsewidthsEditFieldLabel = uilabel(app.UIFigure);
            app.PulsewidthsEditFieldLabel.Text = 'Pulse width (μs)';
            app.SamplerateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.AmplitudemAEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudemAEditFieldLabel.Text = 'Amplitude (mA)';
            app.pulseamp = uieditfield(app.UIFigure, 'numeric');
            app.pulseamp.Value = 1;
            app.Fs = uieditfield(app.UIFigure, 'numeric');
            app.Fs.Value = 100000;
            app.singlepulse = uiaxes(app.UIFigure);
            title(app.singlepulse, 'Single Pulse')
            xlabel(app.singlepulse, 'time')
            ylabel(app.singlepulse, 'Amplitude')
            app.pulsetrain = uiaxes(app.UIFigure);
            title(app.pulsetrain, 'Pulse Train')
            xlabel(app.pulsetrain, 'time')
            ylabel(app.pulsetrain, 'Amplitude')
            app.finalplot = uiaxes(app.UIFigure);
            title(app.finalplot, 'Recorded Data')
            xlabel(app.finalplot, 'time')
            ylabel(app.finalplot, 'Amplitude')
            app.startsession = uibutton(app.UIFigure, 'push');
            app.startsession.ButtonPushedFcn = createCallbackFcn(app, @startsessionButtonPushed, true);
            app.startsession.Text = 'Start Session';
            app.MinimumcurrentamplitudemAEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumcurrentamplitudemAEditFieldLabel.Text = {'Minimum current '; 'amplitude (mA)'};
            app.minrec = uieditfield(app.UIFigure, 'numeric');
            app.minrec.Value = 0.1;
            app.MaximumcurrentamplitudemAEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumcurrentamplitudemAEditFieldLabel.Text = {'Maximum current'; 'amplitude (mA)'};
            app.maxrec = uieditfield(app.UIFigure, 'numeric');
            app.maxrec.Value = 2;
            app.StepsizemAEditFieldLabel = uilabel(app.UIFigure);
            app.StepsizemAEditFieldLabel.Text = 'Step size (mA)';
            app.steprec = uieditfield(app.UIFigure, 'numeric');
            app.steprec.Value = 0.1;
            app.RecruitmentCurveLabel = uilabel(app.UIFigure);
            app.RecruitmentCurveLabel.Text = 'Recruitment Curve';
            app.SaveOptionsLabel = uilabel(app.UIFigure);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.filename = uieditfield(app.UIFigure, 'text');
            app.filename.Value = 'hreflex';
            app.TreatmentLabel = uilabel(app.UIFigure);
            app.TreatmentLabel.Text = 'Treatment';
            app.CNOstate = uidropdown(app.UIFigure);
            app.CNOstate.Items = {'Pre', 'Active', 'Post'};
            app.CNOstate.Value = 'Pre';
            app.autosave = uicheckbox(app.UIFigure);
            app.autosave.Text = 'Autosave';
            app.autosave.Value = true;
            app.startrec = uibutton(app.UIFigure, 'push');
            app.startrec.ButtonPushedFcn = createCallbackFcn(app, @startrecButtonPushed, true);
            app.startrec.Text = 'Start Recruitment Curve';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GeneratePlot = uibutton(app.UIFigure, 'push');
            app.GeneratePlot.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlot.Text = 'Generate Plot';
            app.InputDropDownLabel = uilabel(app.UIFigure);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.UIFigure);
            app.InputDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6'};
            app.InputDropDown.Value = 'ai0';
            app.OutputDropDownLabel = uilabel(app.UIFigure);
            app.OutputDropDownLabel.Text = 'Output';
            app.OutputDropDown = uidropdown(app.UIFigure);
            app.OutputDropDown.Items = {'ao0', 'ao1'};
            app.OutputDropDown.Value = 'ao0';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial #: ';
            app.LabTri = uilabel(app.UIFigure);
            app.LabTri.Text = '';
            app.DirectoryLabel = uilabel(app.UIFigure);
            app.DirectoryLabel.Text = 'Directory:';
            app.DirLabel = uilabel(app.UIFigure);
            app.DirLabel.Text = '';
            app.SelectdefaultCheckBox = uicheckbox(app.UIFigure);
            app.SelectdefaultCheckBox.Text = 'Select default';
            app.SelectdefaultCheckBox.Value = true;
            app.PeriodsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodsecondsEditFieldLabel.Text = 'Period (seconds)';
            app.Period = uieditfield(app.UIFigure, 'numeric');
            app.Period.Value = 3;
            app.minivar = uicheckbox(app.UIFigure);
            app.minivar.Text = 'Minimize Variable Saving';
            app.minivar.Value = true;
            app.Triplicates = uicheckbox(app.UIFigure);
            app.Triplicates.Text = 'Triplicates';
            app.NotesEditFieldLabel = uilabel(app.UIFigure);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.Notes = uieditfield(app.UIFigure, 'text');
            app.CurrentDropDownLabel = uilabel(app.UIFigure);
            app.CurrentDropDownLabel.Text = 'Current';
            app.Current = uidropdown(app.UIFigure);
            app.Current.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6'};
            app.Current.Value = 'N/A';
            app.VoltageDropDownLabel = uilabel(app.UIFigure);
            app.VoltageDropDownLabel.Text = 'Voltage';
            app.Voltage = uidropdown(app.UIFigure);
            app.Voltage.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6'};
            app.Voltage.Value = 'N/A';
            app.TestingInputsLabel = uilabel(app.UIFigure);
            app.TestingInputsLabel.Text = 'Testing Inputs';
            app.MeasuredImpedanceLabel = uilabel(app.UIFigure);
            app.MeasuredImpedanceLabel.Text = 'Measured Impedance:';
            app.impedance = uilabel(app.UIFigure);
            app.impedance.Text = '';
            app.TestImpedanceButton = uibutton(app.UIFigure, 'push');
            app.TestImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @TestImpedanceButtonPushed, true);
            app.TestImpedanceButton.Text = 'Test Impedance';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InterpulsesEditFieldLabel = uilabel(app.UIFigure);
            app.InterpulsesEditFieldLabel.Text = 'Interpulse (s)';
            app.Interpulse = uieditfield(app.UIFigure, 'numeric');
            app.Interpulse.Value = 0.1;
            app.QuickTrigButton = uibutton(app.UIFigure, 'push');
            app.QuickTrigButton.ButtonPushedFcn = createCallbackFcn(app, @QuickTrigButtonPushed, true);
            app.QuickTrigButton.Text = 'Quick Trig';
            app.QuickTrig = uiknob(app.UIFigure, 'continuous');
            app.QuickTrig.Limits = [0.1 5];
            app.QuickTrig.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.75 1 1.5 2 3 4 5];
            app.QuickTrig.MajorTickLabels = {'0.1', '', '', '', '', '0.5', '0.75', '1', '2', '3', '4', '5'};
            app.QuickTrig.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.75 1 1.5 2 3 4 5];
            app.QuickTrig.Value = 0.1;
            app.Analyze = uibutton(app.UIFigure, 'push');
            app.Analyze.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.Analyze.Text = 'Analyze H Reflex';
            app.MHcutoffmsEditFieldLabel = uilabel(app.UIFigure);
            app.MHcutoffmsEditFieldLabel.Text = 'M/H cutoff (ms)';
            app.MHcutoff = uieditfield(app.UIFigure, 'numeric');
            app.MHcutoff.Value = 8;
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadLabel = uilabel(app.UIFigure);
            app.LoadLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hreflex_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end