classdef opto_app_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        QuickTrigButton               matlab.ui.control.Button
        NIDeviceDropDownLabel         matlab.ui.control.Label
        nidev                         matlab.ui.control.DropDown
        NumberofpulsesEditFieldLabel  matlab.ui.control.Label
        npulses                       matlab.ui.control.NumericEditField
        PulsetypeDropDownLabel        matlab.ui.control.Label
        pulsetype                     matlab.ui.control.DropDown
        PulsewidthsEditFieldLabel     matlab.ui.control.Label
        pulsewidth                    matlab.ui.control.NumericEditField
        DelaytimesEditFieldLabel      matlab.ui.control.Label
        delay                         matlab.ui.control.NumericEditField
        SamplerateEditFieldLabel      matlab.ui.control.Label
        Fs                            matlab.ui.control.NumericEditField
        AmplitudemAEditFieldLabel     matlab.ui.control.Label
        pulseamp                      matlab.ui.control.NumericEditField
        gensignal                     matlab.ui.control.UIAxes
        finalplot                     matlab.ui.control.UIAxes
        startsession                  matlab.ui.control.Button
        MinimumcurrentamplitudemAEditFieldLabel  matlab.ui.control.Label
        minrec                        matlab.ui.control.NumericEditField
        MaximumcurrentamplitudemAEditFieldLabel  matlab.ui.control.Label
        maxrec                        matlab.ui.control.NumericEditField
        StepsizemAEditFieldLabel      matlab.ui.control.Label
        steprec                       matlab.ui.control.NumericEditField
        RecruitmentCurveLabel         matlab.ui.control.Label
        SaveOptionsLabel              matlab.ui.control.Label
        FilenameEditFieldLabel        matlab.ui.control.Label
        filename                      matlab.ui.control.EditField
        OpticalStimtypeLabel          matlab.ui.control.Label
        Stimstate                     matlab.ui.control.DropDown
        autosave                      matlab.ui.control.CheckBox
        startrec                      matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        GeneratePlot                  matlab.ui.control.Button
        EStimParametersLabel          matlab.ui.control.Label
        InputDropDownLabel            matlab.ui.control.Label
        InputDropDown                 matlab.ui.control.DropDown
        ElectroOutputDropDownLabel    matlab.ui.control.Label
        ElectroOutputDropDown         matlab.ui.control.DropDown
        StartButton                   matlab.ui.control.Button
        TrialLabel                    matlab.ui.control.Label
        LabTri                        matlab.ui.control.Label
        DirectoryLabel                matlab.ui.control.Label
        DirLabel                      matlab.ui.control.Label
        SelectdefaultCheckBox         matlab.ui.control.CheckBox
        PeriodsecondsEditFieldLabel   matlab.ui.control.Label
        Period                        matlab.ui.control.NumericEditField
        minivar                       matlab.ui.control.CheckBox
        InterpulsesEditFieldLabel     matlab.ui.control.Label
        Interpulse                    matlab.ui.control.NumericEditField
        OptoOutputDropDownLabel       matlab.ui.control.Label
        OptoOutputDropDown            matlab.ui.control.DropDown
        DAQboxsettingsLabel           matlab.ui.control.Label
        OpticalStimulationParametersLabel  matlab.ui.control.Label
        DelaytimeforestimmsEditFieldLabel  matlab.ui.control.Label
        Delaytimeforestimms           matlab.ui.control.NumericEditField
        OpticalPulsewidthmsEditFieldLabel  matlab.ui.control.Label
        OpticalPulsewidthms           matlab.ui.control.NumericEditField
        LightIntensityEditFieldLabel  matlab.ui.control.Label
        LightIntensity                matlab.ui.control.NumericEditField
        QuickTrig                     matlab.ui.control.Knob
        CurrentDropDownLabel          matlab.ui.control.Label
        Current                       matlab.ui.control.DropDown
        VoltageDropDownLabel          matlab.ui.control.Label
        Voltage                       matlab.ui.control.DropDown
        TestImpedanceButton           matlab.ui.control.Button
        TestingInputsLabel            matlab.ui.control.Label
        MeasuredImpedanceLabel        matlab.ui.control.Label
        impedance                     matlab.ui.control.Label
        singlepulse                   matlab.ui.control.UIAxes
        Analyze                       matlab.ui.control.Button
        MHcutoffmsEditFieldLabel      matlab.ui.control.Label
        MHcutoff                      matlab.ui.control.NumericEditField
        LoadDataButton                matlab.ui.control.Button
        LoadLabel                     matlab.ui.control.Label
        RecCurvMsg                    matlab.ui.control.Label
    end


    properties (Access = private)
        trial; %increments for autosaving purposes
        s; %for the daq session
        OptoPulse; 
        PulseTrain; %computation for pulse train wave
        SinglePulse; %computation for single pulse wave
        data; %recorded data
        traintime;
       
        Data;
        indices;
        Opto_Pulse;
        Time;
        Pulse_Train;
        MFP;HFP;MoH;M;H;
        loadedfile;
        adaptation;
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startsession
        function startsessionButtonPushed(app, event)
            app.trial=1;
            a=app.trial;
            app.s=daq.createSession('ni');
            app.s.DurationInSeconds = length(app.PulseTrain)/app.Fs.Value;
            app.s.Rate=app.Fs.Value;
            
            if app.SelectdefaultCheckBox.Value == 1
                % populate the DAQ popup menu. see if we have DAQ toolbox.
                % if so, look for DAQ board. if have one, select it.
                tb=ver;
                tbn={tb.Name};
                %Setting up dev ID
                if ismember('Data Acquisition Toolbox',tbn)
                    gd = daq.getDevices();
                    
                    if isempty(gd) == 1
                        app.nidev.Value ='Sim ';
                    else
                        devID = gd(1).ID;
                        app.nidev.Value = num2str(devID);
                    end
                    
                    % check for DAQ boards, look for NI, choose first one, populate the pop
                    % up menu. set handles.daq accordingly
                else
                    % Don't have DAQ, make Sim  the only option.
                    app.nidev.Value ='Sim ';
                    
                end
                %Setting analog inputs and outputs
                if app.nidev.Value == 'Sim ' %if Sim , dont assign analog inputs/outputs, just update trial number
                    app.LabTri.Text = num2str(a);
                else
                    if app.Current.Value ~= 'N/A' & app.Voltage.Value ~= 'N/A'
                
                        if app.Current.Value == app.Voltage.Value | app.Current.Value == app.InputDropDown.Value | app.Voltage.Value == app.InputDropDown.Value
                
                            app.impedance.Text = 'Input Error'
                            app.impedance.FontColor = [1 0 0];
                            app.impedance.FontWeight = 'bold';
                        end
                    end
                    addAnalogInputChannel(app.s,app.nidev.Value,app.InputDropDown.Value,'Voltage') %recording EMG
                    addAnalogOutputChannel(app.s,app.nidev.Value,app.OutputDropDown.Value,'Voltage') %delivering electric stim
                    
                    if app.Current.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Current.Value,'Voltage') %current testing 
                    end

                    if app.Voltage.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Voltage.Value,'Voltage') %voltage testing
                    end
                    
                    
                    app.LabTri.Text = num2str(a);
                end
                
            else
                if app.nidev.Value == 'Sim ' %if Sim , dont assign analog inputs/outputs, just update trial number
                    app.LabTri.Text = num2str(a);
                else
                   addAnalogInputChannel(app.s,app.nidev.Value,app.InputDropDown.Value,'Voltage') %recording EMG
                   addAnalogOutputChannel(app.s,app.nidev.Value,app.OutputDropDown.Value,'Voltage') %delivering electric stim
                    
                    if app.Current.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Current.Value,'Voltage') %current testing 
                    end

                    if app.Voltage.Value ~= 'N/A'
                        addAnalogInputChannel(app.s,app.nidev.Value,app.Voltage.Value,'Voltage') %voltage testing
                    end
                    
                    app.LabTri.Text = num2str(a);
                end
            end


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        
        
        %Setting the trial number label
            a=app.trial;            
            app.LabTri.Text = num2str(a);
            light_intensity = 5.0001 - app.LightIntensity.Value;
            
            %Detecting stim-type
            
            %for ChR2 excitatory stimulation
            if app.Stimstate.Value == 'Excitatory'
                width_ms = app.OpticalPulsewidthms.Value*1e-3;
                SingleOptoPulse = [light_intensity*ones(width_ms*app.Fs.Value,1)
                                   5*ones(app.Fs.Value*app.Interpulse.Value,1)];
                app.OptoPulse = [5*ones(app.Fs.Value*app.delay.Value,1);...
                                repmat(SingleOptoPulse,app.npulses.Value,1)]; % n optical pulses with a known light intensity
                
                %No E-stim necessary in this case so we just go ahead and
                %deliver the pulse
                
                
                %but first we need to plot the created pulse
            
                %First of all, plotting the single pulse
                PlottedSingleOpto = 5 - [5*ones(2e-3*app.Fs.Value,1);light_intensity*ones(width_ms*app.Fs.Value,1);5*ones(2e-3*app.Fs.Value,1)];               
                SingleTime = [(1:length(PlottedSingleOpto))/app.Fs.Value];
                
                %Then the pulse train
                plot(app.singlepulse,SingleTime,PlottedSingleOpto,'b');
                xlabel(app.singlepulse,'Time (s)');
                ylabel(app.singlepulse,'Light Intensity');
                legend(app.singlepulse,'Light');
                title(app.singlepulse,'Single Pulse');
                
                app.traintime=[(1:length(app.OptoPulse))/app.Fs.Value]; %defining time vector
                PlottedOptoPulse = 5 - [app.OptoPulse]; %reversing the pulse for plotting
                
                plot(app.gensignal,app.traintime,PlottedOptoPulse,'b');
                xlabel(app.gensignal,'Time (s)');
                ylabel(app.gensignal,'Light Intensity');
                legend(app.gensignal,'Light');
                
                %delivering the pulse train through the daq board
               if app.nidev.Value == 'Sim '
                    L = length(app.OptoPulse);
                    app.data = -0.1*rand(L,1)+0.05;
                
                    plot(app.finalplot,app.traintime,PlottedOptoPulse,'b',app.traintime,app.data,'k');
                    xlabel(app.finalplot, 'Time (s)');
                    ylabel(app.finalplot, 'Amplitude');
                    legend(app.finalplot,'Light','Data');


                else
%                 
                    L= length(app.OptoPulse);
                    app.PulseTrain = zeros(L,1);
                
                    queueOutputData(app.s,[app.PulseTrain app.OptoPulse]);
                    app.data=startForeground(app.s);
                
                    plot(app.singlepulse, a)
                    plot(app.finalplot,app.traintime,PlottedOptoPulse,'b',app.traintime,app.data,'k');
                    xlabel(app.finalplot, 'Time (s)');
                    ylabel(app.finalplot, 'Amplitude');
                    legend(app.finalplot,'Light','Data');
                end
            
                
            elseif app.Stimstate.Value == 'Inhibitory'


             plot(app.singlepulse,singletime,singlePestim,'r',singletime,singlePopto,'g')%Creating the optical pulse    
                SingleOptoPulse = [light_intensity*ones(1e-3*app.Fs.Value*app.OpticalPulsewidthms.Value,1);...
                                   5*ones(app.Fs.Value*app.Interpulse.Value,1)];                   
                
                
                app.OptoPulse = [5*ones(app.Fs.Value*app.delay.Value,1);...
                                 repmat(SingleOptoPulse,app.npulses.Value,1)];
                
           %Creating the electric pulse train
                        
            if app.pulsetype.Value == 'Bipolar EstimPulse'
                EPulse = [app.pulseamp.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1) ;...
                       -1*app.pulseamp.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];
            
            
            end
            if app.pulsetype.Value == 'Positive Monopolar'
                EPulse = [app.pulseamp.Value*ones(app.Fs.Value * app.pulsewidth.Value*1e-6,1); ...
                    zeros(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];
            end
            if  app.pulsetype.Value == 'Negative Monopolar'
                EPulse = [-1*app.pulseamp.Value*ones(app.Fs.Value * app.pulsewidth.Value*1e-6,1); ...
                    zeros(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];
                      
            end
            
    %variable computing the time off for the e-stim to reach same length as opto stim
        missingtime= 1e-3*app.Fs.Value*app.OpticalPulsewidthms.Value - ...
                     1e-3*app.Fs.Value*app.Delaytimeforestimms.Value - ...
                     length(EPulse); 
            
             TotalEPulse = [zeros(1e-3*app.Fs.Value*app.Delaytimeforestimms.Value,1);...
                            EPulse;...
                            zeros(missingtime,1);...
                            zeros(app.Fs.Value*app.Interpulse.Value,1)];
            
                            
            singlePopto=[zeros(2e-3*app.Fs.Value,1);...
                         app.LightIntensity.Value*ones(1e-3*app.OpticalPulsewidthms.Value*app.Fs.Value,1);...
                         zeros(2e-3*app.Fs.Value,1)];
                     
            singlePestim=[zeros(2e-3*app.Fs.Value,1);zeros(app.Fs.Value*app.Delaytimeforestimms.Value*1e-3,1);...
                           EPulse;...
                           zeros(missingtime,1);...
                           zeros(2e-3*app.Fs.Value,1)];
                       
            singletime= [(1:length(singlePopto))/app.Fs.Value];          
             xlabel(app.singlepulse,'time(s)')
             ylabel(app.singlepulse,'Volts(V)')
             legend(app.singlepulse,'e-stim','opto stim')
             title(app.singlepulse,'single pulse')
             app.PulseTrain = [zeros(app.Fs.Value*app.delay.Value,1);...
                               repmat(TotalEPulse,app.npulses.Value,1)];
            
            
            %Creating time vector        
           app.traintime  = [(1:length(app.OptoPulse))/app.Fs.Value];
           
           PlottedOptoPulse = 5- app.OptoPulse; %reversing the pulse for plotting
                
                plot(app.gensignal,app.traintime,PlottedOptoPulse,'g',...
                    app.traintime,app.PulseTrain,'r');
                xlabel(app.gensignal,'Time (s)');
                ylabel(app.gensignal,'Light Intensity');
                legend(app.gensignal,'Light','E-Stim');
                
                %delivering the pulse train through the daq board
                if app.nidev.Value =='Sim '
                L = length(app.OptoPulse);
                app.data = -0.1*rand(L,1)+0.05;
                
                plot(app.finalplot,app.traintime,PlottedOptoPulse,'g',app.traintime,app.PulseTrain,'r',app.traintime,app.data,'k');
                xlabel(app.finalplot, 'Time (s)');
                ylabel(app.finalplot, 'Amplitude');
                legend(app.finalplot,'Light','E-Stim','Data');
             
                
                else
                queueOutputData(app.s,[app.PulseTrain app.OptoPulse]);
                app.data=startForeground(app.s);
                
                plot(app.finalplot,app.traintime,PlottedOptoPulse,'g',app.traintime,app.PulseTrain,'r',app.traintime,app.data,'k');
                xlabel(app.finalplot, 'Time (s)');
                ylabel(app.finalplot, 'Amplitude');
                legend(app.finalplot,'Light','E-Stim','Data');
                end
            
            end
    
            %Autosaving
            
            FileName=[app.filename.Value,'_',app.Stimstate.Value,'_',num2str(app.trial)];
            
            
        if app.minivar.Value == 1
            threshold = app.OptoPulse < 5;
            app.indices = find ((diff(threshold))>0.5);

            if app.Stimstate.Value == 'Inhibitory' 
            
            for i= 1:(length(app.indices))
                app.Opto_Pulse {i} = app.OptoPulse( ...
                    (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                    (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );

                app.Pulse_Train {i} = app.PulseTrain( ...
                    (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                    (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );
                
                app.Data {i} = app.data( ...
                    (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                    (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );
                
                    app.Time {i} = linspace (-2e-3,(app.OpticalPulsewidthms.Value + 2),length(app.Opto_Pulse{i}));
            end
            elseif app.Stimstate.Value == 'Excitatory'
                for i= 1:(length(app.indices))
                app.Opto_Pulse {i} = app.OptoPulse( ...
                    (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                    (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );

                app.Pulse_Train = [];
                app.Data {i} = app.data( ...
                    (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                    (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );
                
                    app.Time {i} = linspace (-2e-3,(app.OpticalPulsewidthms.Value + 2),length(app.Opto_Pulse{i}));
                
                end
            end
            Opto_Pulse=app.Opto_Pulse;Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;indices=app.indices;
            save (FileName,'app','Opto_Pulse','Time','Data','Pulse_Train','indices')   
            
            else 
                
            app.Opto_Pulse = app.OptoPulse;
            app.Time = app.traintime;
            app.Data = app.data;
            app.Pulse_Train = app.PulseTrain;
           
            Opto_Pulse=app.Opto_Pulse;Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;indices=app.indices;
            save (FileName,'app','Opto_Pulse','Time','Data','Pulse_Train','indices')
end

            b=cd;
            CDname=[b,'/',FileName];
            app.DirLabel.Text = num2str(CDname);           
            
            app.trial=app.trial +1;
           
        
        end

        % Button pushed function: QuickTrigButton
        function QuickTrigButtonButtonPushed(app, event)
              
            %Creating the pulse train
            Pulse = [app.QuickTrig.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1) ;...
                               -app.QuickTrig.Value*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];


            %Creating the quick trigger pulse
            app.SinglePulse = [zeros(app.Fs.Value*200e-6,1);Pulse;zeros(app.Fs.Value*20e-3,1)]; 
            
            app.PulseTrain = app.SinglePulse;
            
            %Creating time vectors
            SingleTime = [(1:length(app.SinglePulse))/app.Fs.Value];
            app.traintime  = [(1:length(app.PulseTrain))/app.Fs.Value];
            
           %Plotting single pulse and pulse trains
              plot(app.gensignal,app.traintime,app.PulseTrain);
              xlabel(app.gensignal,'Time (s)');
              ylabel(app.gensignal,'Amplitude (mA)');
              
            %Acquiring Data
            if app.nidev.Value =='Sim '
                L = length(app.PulseTrain);
                app.data = zeros(L,1);
                
            else
            L = length(app.PulseTrain);
            queueOutputData(app.s,[app.PulseTrain 5*ones(L,1)]);
            app.data=startForeground(app.s);
            
            end
            
            %plotting the response
            plot(app.finalplot,app.traintime,app.PulseTrain,'r',app.traintime,app.data,'k');
            xlabel(app.finalplot, 'Time (s)');
            ylabel(app.finalplot, 'Amplitude');
            legend(app.finalplot, 'Electric Stimulation','Response');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Time = app.traintime;
            Data = app.data;
            Pulse_Train = app.PulseTrain;
            
            FileName=[app.filename.Value,'_',app.Stimstate.Value,'_Trial_',num2str(app.trial)];
            save(FileName)
            b=cd;
            CDname=[b,'/',FileName,'.mat'];
            app.DirLabel.Text = num2str(CDname);          
        end

        % Button pushed function: GeneratePlot
        function GeneratePlotButtonPushed(app, event)
         figure (1)
         
         if app.Stimstate.Value == 'Excitatory'
             
             plot(app.traintime,5-app.OptoPulse,'b',...
                  app.traintime,app.data,'k')
              
                  legend('Optical Stimulation','Response')
                  xlabel('Time (s)')
                  ylabel('Amplitude')
         
        
         elseif app.Stimstate.Value == 'Inhibitory'
             
          plot(app.traintime, 5-app.OptoPulse,'g',...
               app.traintime,app.PulseTrain,'r',...
               app.traintime,app.data,'k') %plotting pluse train and acquired data against time
          legend('Optical Stimulaion','Electric Stimulation','Response')
          xlabel('Time');
          ylabel('Amplitude');
        
        end
        end

        % Button pushed function: startrec
        function startrecButtonPushed(app, event)
          light_intensity = 5.0001 - app.LightIntensity.Value;  
        
        %Recruitment Curve based on minimum value, maximum value and step size
            if app.Stimstate.Value == 'Excitatory'
                app.RecCurvMsg.Text = 'Select Inhibitory Stim Type for Recruitment Curves';
            else
                
                app.RecCurvMsg.Text='';
                a=app.trial;
                app.LabTri.Text = num2str(a);
                
                int = app.minrec.Value; %setting amplitude as the minimum recruitment value
                
                Eonepulse = 0;
                OPulse = 0;
                while int <= app.maxrec.Value
                    %Create pulse as long as the amplitude is lesser than the maximum recruitment value
                    
                    
                    %Creating the electric pulse train
                  if app.pulsetype.Value == 'Bipolar EstimPulse'
                        EPulse = [int*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1) ;...
                            -1*int*ones(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];
                        
                        
                  elseif app.pulsetype.Value == 'Positive Monopolar'
                        EPulse = [int*ones(app.Fs.Value * app.pulsewidth.Value*1e-6,1); ...
                            zeros(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];
                  elseif  app.pulsetype.Value == 'Negative Monopolar'
                        EPulse = [-1*int*ones(app.Fs.Value * app.pulsewidth.Value*1e-6,1); ...
                            zeros(app.Fs.Value*app.pulsewidth.Value*1e-6,1)];
                        
                  end
                  
                    
                    %variable computing the time off for the e-stim to reach same length as opto stim
                    missingtime= 1e-3*app.Fs.Value*app.OpticalPulsewidthms.Value - ...
                        1e-3*app.Fs.Value*app.Delaytimeforestimms.Value - ...
                        length(EPulse);
                    
                    TotalEPulse = [zeros(1e-3*app.Fs.Value*app.Delaytimeforestimms.Value,1);...
                        EPulse;...
                        zeros(missingtime,1);zeros(app.Fs.Value*app.Period.Value,1)];
                    
                    SingleOptoPulse = [light_intensity*ones(1e-3*app.Fs.Value*app.OpticalPulsewidthms.Value,1);...
                        5*ones(app.Fs.Value*app.Period.Value,1)];
                    
                    Eonepulse = [Eonepulse;TotalEPulse];
                    OPulse = [OPulse;SingleOptoPulse];
                %every pulse lasts for one second
                
                int = int + app.steprec.Value; %incrementing by the step size
                end
 
              
            app.PulseTrain = [zeros(800*1e-3*app.Fs.Value,1); Eonepulse; zeros(50e-3*app.Fs.Value,1)];
            app.traintime = [(1:length(app.PulseTrain))/app.Fs.Value];
            app.OptoPulse = [5*ones(app.Fs.Value*800*1e-3,1);OPulse;5*ones(50e-3*app.Fs.Value,1)];
            %Plotting the Pulse Train
            
            
            plot(app.gensignal,app.traintime,app.PulseTrain,'r',app.traintime,5-app.OptoPulse,'g')
            xlabel(app.gensignal,'Time (s)');
            ylabel(app.gensignal,'Amplitude (mA)');
            legend(app.gensignal,'E-Stim','Light Pulse')
            title(app.gensignal,'Recruitment Curve Pulse Train')
            end


            %Acquiring Data
            if app.nidev.Value =='Sim '
                L = length(app.OptoPulse);
                app.data = -0.1*rand(L,1)+0.05;
                
                plot(app.finalplot,app.traintime,5-app.OptoPulse,'g',app.traintime,app.PulseTrain,'r',app.traintime,app.data,'k');
                xlabel(app.finalplot, 'Time (s)');
                ylabel(app.finalplot, 'Amplitude');
                legend(app.finalplot,'Light','E-Stim','Data');
                
                
            else
                queueOutputData(app.s,[app.PulseTrain app.OptoPulse]);
                app.data=startForeground(app.s);
                
                plot(app.finalplot,app.traintime,5-app.OptoPulse,'g',app.traintime,app.PulseTrain,'r',app.traintime,app.data,'k');
                xlabel(app.finalplot, 'Time (s)');
                ylabel(app.finalplot, 'Amplitude');
                legend(app.finalplot,'Light','E-Stim','Data');
            end
            
            
            %Saving data
            FileName=[app.filename.Value,'_Recruitment_Curve_',app.Stimstate.Value,'_',num2str(app.trial)];
            
            
            if app.minivar.Value == 1
                threshold = app.OptoPulse < 5;
                app.indices = find ((diff(threshold))>0.5);

                    
                    for i= 1:(length(app.indices))
                        app.Opto_Pulse {i} = app.OptoPulse( ...
                            (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                            (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );
                        
                        app.Pulse_Train {i} = app.PulseTrain( ...
                            (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                            (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );
                        
                        app.Data {i} = app.data( ...
                            (app.indices(i)- (2e-3 * app.Fs.Value)) : ...
                            (app.indices(i) + (1e-3*app.Fs.Value*(app.OpticalPulsewidthms.Value + 2))) );
                        
                        app.Time {i} = linspace (-2e-3,(app.OpticalPulsewidthms.Value + 2),length(app.Opto_Pulse{i}));
                    end

            
                Opto_Pulse=app.Opto_Pulse;Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;indices=app.indices;
                save (FileName,'app','Opto_Pulse','Time','Data','Pulse_Train','indices')
                
            else
                
                app.Opto_Pulse = app.OptoPulse;
                app.Time = app.traintime;
                app.Data = app.data;
                app.Pulse_Train = app.PulseTrain;
                
                Opto_Pulse=app.Opto_Pulse;Time=app.Time;Data=app.Data;Pulse_Train=app.Pulse_Train;indices=app.indices;
                save (FileName,'app','Opto_Pulse','Time','Data','Pulse_Train','indices')
            end
            
            b=cd;
            CDname=[b,'/',FileName];
            app.DirLabel.Text = num2str(CDname);
            
            app.trial=app.trial +1;
            
        end

        % Button pushed function: TestImpedanceButton
        function TestImpedanceButtonPushed(app, event)
                        
            if isempty(app.trial) == 1
                app.impedance.Text = 'Start Session';
                app.impedance.FontColor = [1 0 0];
                app.impedance.FontWeight = 'bold';
            
            else
            
            if app.Current.Value == 'N/A' | app.Voltage.Value == 'N/A'
                        app.impedance.Text = 'Choose Inputs';
                        app.impedance.FontColor = [1 0 0];
                        app.impedance.FontWeight = 'bold';
            elseif app.Current.Value == app.Voltage.Value | ...
                   app.Current.Value == app.InputDropDown.Value  | ...
                   app.Voltage.Value == app.InputDropDown.Value 
                  
                        app.impedance.Text = 'Error Inputs';
                        app.impedance.FontColor = [1 0 0];
                        app.impedance.FontWeight = 'bold';
                    
            else
                
            
                testramp = linspace(0,1,app.Fs.Value)'; 
                %ramp from 0 to 1 mA for 1 second
                if app.nidev.Value == 'Sim '
                    L = length(testramp);
                    vv = randn(L,1);
                    ii = randn(L,1);
                else
                queueOutputData(app.s,testramp);
                data=startForeground(app.s);
                
               
                vv = 100*data(((app.Fs.Value)/10):(app.Fs.Value),3);
                ii = data(((app.Fs.Value)/10):(app.Fs.Value),2);
                end
                
                iim = 1e-3*ii;
            
            %plotting the test results
            
            plot(app.singlepulse,ii(:,1),vv(:,1));
                        
            xlabel(app.singlepulse,'Current (mA)');
            ylabel(app.singlepulse,'Voltage (V)');
            title(app.singlepulse,'Testing Results');
            
            if app.nidev.Value == 'Sim '
                plot(app.gensignal,testramp(((app.Fs.Value)/10):app.Fs.Value),ii(((app.Fs.Value)/10):app.Fs.Value));
            else
            
                plot(app.gensignal,testramp(((app.Fs.Value)/10):app.Fs.Value),ii(:,1));
            end
            xlabel(app.gensignal,'Command (mA)');
            ylabel(app.gensignal,'Response (mA)');
            title(app.gensignal,'Current Accuracy');
            
            if app.nidev.Value == 'Sim '
                polycom = polyfit(testramp(((app.Fs.Value)/10):(app.Fs.Value)),ii(((app.Fs.Value)/10):(app.Fs.Value)),1);
            else
                polycom = polyfit(testramp(((app.Fs.Value)/10):(app.Fs.Value)),ii(:,1));
            end
            comtxt = ['y = ',num2str(polycom(1)),' * x + ',num2str(polycom(2))];
            text(app.gensignal,min(ii)+0.1,max(testramp(((app.Fs.Value)/10):(app.Fs.Value))),comtxt);
            
            
            %creating a fit and displaying the equation
            p=polyfit(iim,vv,1);
            Rleg = p(1) -1000;
            
            if Rleg < 5000 && Rleg > 15 000
                app.impedance.FontColor = [1 0 0];
                app.impedance.FontWeight = 'bold';
            else 
                app.impedance.FontColor = [0 1 0];
                app.impedance.FontWeight = 'bold';
            end
            
            impy = [num2str(Rleg) 'ohms'];
            
            app.impedance.Text = impy;
            txt = ['y = ',num2str(Rleg),' * x + ',num2str(p(2))];
            text(app.singlepulse,min(ii)+0.1,max(vv),txt);
           
                
            end
            end
        
   
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            figure(app.UIFigure);

            if isequal(file,0)
             app.LoadLabel.Text='User Cancelled';
            else
             app.LoadLabel.Text=num2str(fullfile(path,file));
             app.loadedfile = load(fullfile(path,file));
            end
        end

        % Button pushed function: Analyze
        function AnalyzeButtonPushed(app, event)
       if isempty(app.loadedfile) == 0
            app.indices=app.loadedfile.indices;
            app.Data = app.loadedfile.Data;
            app.Time = app.loadedfile.Time;
            app.Pulse_Train = app.loadedfile.Pulse_Train;
        
        end

        cutoff = (app.MHcutoff.Value+2)*1e-3*app.Fs.Value; %acquisition starts 2ms before stim, then determining point value in X
        for i=1:(length(app.indices))
            dat = app.Data{i};
            
            datnoise = dat(1:185);
            noiselevel=1.75*max(abs(datnoise));
           
            
            datM = dat(250:cutoff); %M wave should be between 250 (afer stim artifact) and the H/M cutoff
            datH = dat(cutoff:length(dat));%H wave should be afer H/M cutoff
            
            maxM = max(abs(datM));
            maxH = max(abs(datH));
            
            maxMp = max(datM);
            maxHp = max(datH);
            
            if maxMp ~= maxM
            maxMp = - maxM;
            end
            if maxHp ~= maxH
            maxHp = -maxH;
            end
            
            
            locM = find(dat==maxMp);
            locH = find(dat==maxHp);
            
            if maxM < noiselevel
            maxMp = 0;
            maxM = 0;
            end
            if maxH < noiselevel
            maxHp = 0;
            maxH = 0;
            end
           
            int=max(app.Pulse_Train{i});
            figure(i) 
            plot(app.Time{i},dat);
            xline(app.MHcutoff.Value*1e-3,'r','M/H Cutoff');
            hold on
            if maxMp ~= 0 && maxHp ~= 0
                plot(locM/1e5-2e-3,maxMp,'m*')
                plot(locH/1e5-2e-3,maxHp,'m*')
            elseif maxMp == 0 && maxHp ~= 0
                plot(locH/1e5-2e-3,maxHp,'m*')
                text(4e-3,0,'No M')
            elseif maxMp ~= 0 && maxHp == 0
                plot(locM/1e5-2e-3,maxMp,'m*')
                text(12e-3,0,'no H')
            elseif maxMp == 0 && maxHp == 0
                text(4e-3,0,'No M')
                text(12e-3,0,'no H')
            end
            hold off
            label = ['Stim at ',num2str(int),'mA'];
            xlabel('time(s)')
            ylabel('Response (x1000 V)')
            title(label)
            
            M{i}= maxM;

            H{i}= maxH;
            
        end
        app.MFP = M{1};
        app.HFP = H{1};
        for i=2:(length(app.indices))
            app.MFP = [app.MFP M{i}];
            app.HFP = [app.HFP H{i}];
        end
        app.MoH = app.HFP./app.MFP; %vector containing the H to M ratios
       
        HMmax=max(app.HFP)/max(app.MFP);
        IVals = max(app.Pulse_Train{1});
        for i=2:length(app.Pulse_Train)
            IVals=[IVals max(app.Pulse_Train{i})]; %compute current intensities in IVals
        end
        if IVals(1) == IVals(2) %when loading data, if all IVals members are the same, then a frequency adapation was performed
            app.adaptation =1;
        end
        
        if app.adaptation == 1
            IVals = 1:length(IVals); %if freq adaptation function is used, instead of plotting against current values, plot against pulse number
        end
            
        figure()
        plot(IVals, app.MFP,'*-r',IVals,app.HFP,'*-b') %plot M and H versus I
        
        ylabel('Voltage (V)')
        legend('M Value','H Value')
        if app.adaptation == 1
            title('Variation of M and H Values with different pulses')
            xlabel('Pulse number')
        else
            title('Variation of M and H values with E-stim ampltitudes')
            xlabel('Current (mA)')
        end
        
        figure()
        plot(IVals,app.MoH,'-o')
        yline(HMmax,'r','Hmax/Mmax')
        xlabel('Current(mA)')
        ylabel('H to M ratio')
        if app.adaptation == 1
            title('Variation of H to M ratios with different pulses')
            xlabel('Pulse number')
        else
            title('Variation of H to M ratios with E-stim amplitudes')
            xlabel('Current (mA)')
        end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.QuickTrigButton = uibutton(app.UIFigure, 'push');
            app.QuickTrigButton.ButtonPushedFcn = createCallbackFcn(app, @QuickTrigButtonButtonPushed, true);
            app.QuickTrigButton.Text = 'Quick Trig';
            app.NIDeviceDropDownLabel = uilabel(app.UIFigure);
            app.NIDeviceDropDownLabel.Text = 'NI Device';
            app.nidev = uidropdown(app.UIFigure);
            app.nidev.Items = {'Dev1', 'Dev2', 'Dev3', 'Dev4', 'Sim '};
            app.nidev.Value = 'Dev1';
            app.NumberofpulsesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpulsesEditFieldLabel.Text = 'Number of pulses';
            app.npulses = uieditfield(app.UIFigure, 'numeric');
            app.npulses.Value = 3;
            app.PulsetypeDropDownLabel = uilabel(app.UIFigure);
            app.PulsetypeDropDownLabel.Text = 'Pulse type';
            app.pulsetype = uidropdown(app.UIFigure);
            app.pulsetype.Items = {'Bipolar EstimPulse', 'Positive Monopolar', 'Negative Monopolar'};
            app.pulsetype.Value = 'Bipolar EstimPulse';
            app.PulsewidthsEditFieldLabel = uilabel(app.UIFigure);
            app.PulsewidthsEditFieldLabel.Text = 'Pulse width (μs)';
            app.pulsewidth = uieditfield(app.UIFigure, 'numeric');
            app.pulsewidth.Value = 100;
            app.DelaytimesEditFieldLabel = uilabel(app.UIFigure);
            app.DelaytimesEditFieldLabel.Text = 'Delay time (s)';
            app.delay = uieditfield(app.UIFigure, 'numeric');
            app.delay.Value = 1;
            app.SamplerateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.Fs = uieditfield(app.UIFigure, 'numeric');
            app.Fs.Value = 100000;
            app.AmplitudemAEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudemAEditFieldLabel.Text = 'Amplitude (mA)';
            app.pulseamp = uieditfield(app.UIFigure, 'numeric');
            app.pulseamp.Value = 1;
            app.gensignal = uiaxes(app.UIFigure);
            title(app.gensignal, 'Generated Signal')
            xlabel(app.gensignal, 'time')
            ylabel(app.gensignal, 'Amplitude')
            app.finalplot = uiaxes(app.UIFigure);
            title(app.finalplot, 'Recorded Data')
            xlabel(app.finalplot, 'time')
            ylabel(app.finalplot, 'Amplitude')
            app.startsession = uibutton(app.UIFigure, 'push');
            app.startsession.ButtonPushedFcn = createCallbackFcn(app, @startsessionButtonPushed, true);
            app.startsession.Text = 'Start Session';
            app.MinimumcurrentamplitudemAEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumcurrentamplitudemAEditFieldLabel.Text = {'Minimum current '; 'amplitude (mA)'};
            app.minrec = uieditfield(app.UIFigure, 'numeric');
            app.minrec.Value = 0.1;
            app.MaximumcurrentamplitudemAEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumcurrentamplitudemAEditFieldLabel.Text = {'Maximum current'; 'amplitude (mA)'};
            app.maxrec = uieditfield(app.UIFigure, 'numeric');
            app.maxrec.Value = 2;
            app.StepsizemAEditFieldLabel = uilabel(app.UIFigure);
            app.StepsizemAEditFieldLabel.Text = 'Step size (mA)';
            app.steprec = uieditfield(app.UIFigure, 'numeric');
            app.steprec.Value = 0.1;
            app.RecruitmentCurveLabel = uilabel(app.UIFigure);
            app.RecruitmentCurveLabel.Text = 'Recruitment Curve';
            app.SaveOptionsLabel = uilabel(app.UIFigure);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.filename = uieditfield(app.UIFigure, 'text');
            app.filename.Value = 'Opto';
            app.OpticalStimtypeLabel = uilabel(app.UIFigure);
            app.OpticalStimtypeLabel.Text = 'Optical Stim type';
            app.Stimstate = uidropdown(app.UIFigure);
            app.Stimstate.Items = {'Excitatory', 'Inhibitory'};
            app.Stimstate.Value = 'Excitatory';
            app.autosave = uicheckbox(app.UIFigure);
            app.autosave.Text = 'Autosave';
            app.autosave.Value = true;
            app.startrec = uibutton(app.UIFigure, 'push');
            app.startrec.ButtonPushedFcn = createCallbackFcn(app, @startrecButtonPushed, true);
            app.startrec.Text = 'Start Recruitment Curve';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GeneratePlot = uibutton(app.UIFigure, 'push');
            app.GeneratePlot.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlot.Text = 'Generate Plot';
            app.EStimParametersLabel = uilabel(app.UIFigure);
            app.EStimParametersLabel.Text = 'E-Stim Parameters';
            app.InputDropDownLabel = uilabel(app.UIFigure);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.UIFigure);
            app.InputDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6'};
            app.InputDropDown.Value = 'ai0';
            app.ElectroOutputDropDownLabel = uilabel(app.UIFigure);
            app.ElectroOutputDropDownLabel.Text = 'Electro Output';
            app.ElectroOutputDropDown = uidropdown(app.UIFigure);
            app.ElectroOutputDropDown.Items = {'ao0', 'ao1'};
            app.ElectroOutputDropDown.Value = 'ao1';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial #: ';
            app.LabTri = uilabel(app.UIFigure);
            app.LabTri.Text = '';
            app.DirectoryLabel = uilabel(app.UIFigure);
            app.DirectoryLabel.Text = 'Directory:';
            app.DirLabel = uilabel(app.UIFigure);
            app.DirLabel.Text = '';
            app.SelectdefaultCheckBox = uicheckbox(app.UIFigure);
            app.SelectdefaultCheckBox.Text = 'Select default';
            app.SelectdefaultCheckBox.Value = true;
            app.PeriodsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodsecondsEditFieldLabel.Text = 'Period (seconds)';
            app.Period = uieditfield(app.UIFigure, 'numeric');
            app.Period.Value = 3;
            app.minivar = uicheckbox(app.UIFigure);
            app.minivar.Text = 'Minimize Variable Saving';
            app.minivar.Value = true;
            app.InterpulsesEditFieldLabel = uilabel(app.UIFigure);
            app.InterpulsesEditFieldLabel.Text = 'Interpulse (s)';
            app.Interpulse = uieditfield(app.UIFigure, 'numeric');
            app.Interpulse.Value = 0.1;
            app.OptoOutputDropDownLabel = uilabel(app.UIFigure);
            app.OptoOutputDropDownLabel.Text = 'Opto Output';
            app.OptoOutputDropDown = uidropdown(app.UIFigure);
            app.OptoOutputDropDown.Items = {'ao0', 'ao1'};
            app.OptoOutputDropDown.Value = 'ao0';
            app.DAQboxsettingsLabel = uilabel(app.UIFigure);
            app.DAQboxsettingsLabel.Text = 'DAQ box settings';
            app.OpticalStimulationParametersLabel = uilabel(app.UIFigure);
            app.OpticalStimulationParametersLabel.Text = 'Optical Stimulation Parameters';
            app.DelaytimeforestimmsEditFieldLabel = uilabel(app.UIFigure);
            app.DelaytimeforestimmsEditFieldLabel.Text = 'Delay time for e-stim (ms)';
            app.Delaytimeforestimms = uieditfield(app.UIFigure, 'numeric');
            app.OpticalPulsewidthmsEditFieldLabel = uilabel(app.UIFigure);
            app.OpticalPulsewidthmsEditFieldLabel.Text = 'Optical Pulse width (ms)';
            app.OpticalPulsewidthms = uieditfield(app.UIFigure, 'numeric');
            app.OpticalPulsewidthms.Value = 20;
            app.LightIntensityEditFieldLabel = uilabel(app.UIFigure);
            app.LightIntensityEditFieldLabel.Text = 'Light Intensity';
            app.LightIntensity = uieditfield(app.UIFigure, 'numeric');
            app.LightIntensity.Limits = [0 5];
            app.LightIntensity.Value = 5;
            app.QuickTrig = uiknob(app.UIFigure, 'continuous');
            app.QuickTrig.Limits = [0.1 5];
            app.QuickTrig.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.75 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.QuickTrig.MajorTickLabels = {'0.1', '', '', '', '0.5', '0.75', '1', '', '2', '', '3', '', '4', '', '5'};
            app.QuickTrig.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.75 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.QuickTrig.Value = 0.1;
            app.CurrentDropDownLabel = uilabel(app.UIFigure);
            app.CurrentDropDownLabel.Text = 'Current';
            app.Current = uidropdown(app.UIFigure);
            app.Current.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6'};
            app.Current.Value = 'N/A';
            app.VoltageDropDownLabel = uilabel(app.UIFigure);
            app.VoltageDropDownLabel.Text = 'Voltage';
            app.Voltage = uidropdown(app.UIFigure);
            app.Voltage.Items = {'N/A', 'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6'};
            app.Voltage.Value = 'N/A';
            app.TestImpedanceButton = uibutton(app.UIFigure, 'push');
            app.TestImpedanceButton.ButtonPushedFcn = createCallbackFcn(app, @TestImpedanceButtonPushed, true);
            app.TestImpedanceButton.Text = 'Test Impedance';
            app.TestingInputsLabel = uilabel(app.UIFigure);
            app.TestingInputsLabel.Text = 'Testing Inputs';
            app.MeasuredImpedanceLabel = uilabel(app.UIFigure);
            app.MeasuredImpedanceLabel.Text = 'Measured Impedance:';
            app.impedance = uilabel(app.UIFigure);
            app.impedance.Text = '';
            app.singlepulse = uiaxes(app.UIFigure);
            title(app.singlepulse, 'Single Pulse')
            xlabel(app.singlepulse, 'time')
            ylabel(app.singlepulse, 'Amplitude')
            app.Analyze = uibutton(app.UIFigure, 'push');
            app.Analyze.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.Analyze.Text = 'Analyze H Reflex';
            app.MHcutoffmsEditFieldLabel = uilabel(app.UIFigure);
            app.MHcutoffmsEditFieldLabel.Text = 'M/H cutoff (ms)';
            app.MHcutoff = uieditfield(app.UIFigure, 'numeric');
            app.MHcutoff.Value = 8;
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadLabel = uilabel(app.UIFigure);
            app.LoadLabel.Text = '';
            app.RecCurvMsg = uilabel(app.UIFigure);
            app.RecCurvMsg.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_app_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end